classdef macao < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ImparteCartiButton  matlab.ui.control.Button
        TrageCarteButton    matlab.ui.control.Button
        LasaCarteButton     matlab.ui.control.Button
        CartiListBox        matlab.ui.control.ListBox
        CartiListBoxLabel   matlab.ui.control.Label
        DeckCardAxes        matlab.ui.control.UIAxes
        OpponentCardAxes    matlab.ui.control.UIAxes
        TableCardAxes       matlab.ui.control.UIAxes
        PlayerCardAxes      matlab.ui.control.UIAxes
    end

     properties (Access = private)
        TableCard          char
        DrawnCard          char
        Deck               cell
        PlayerHand         cell
        OpponentHand       cell
        CardsFolderPath    char = 'C:\Users\bstwl\OneDrive\Desktop\proiect matlab\carti';
        BackCardImage      char = 'spate.png';
        GameOver           logical = false;
    end

    methods (Access = private)

        function createDeck(app)
            suits = {'negru', 'romb', 'rosu', 'trefla'};
            ranks = {'as', '2', '3', '4', '5', '6', '7', '8', '9', '10', 'j', 'q', 'k'};

            app.Deck = cell(1, numel(suits) * numel(ranks));

            idx = 1;
            for i = 1:numel(suits)
                for j = 1:numel(ranks)
                    app.Deck{idx} = [ranks{j}, '_', suits{i}];
                    idx = idx + 1;
                end
            end
        end

       function shuffleDeck(app)
            app.Deck = app.Deck(randperm(length(app.Deck)));
        end

         function dealCards(app)
    app.PlayerHand = app.Deck(1:5);
    app.OpponentHand = app.Deck(6:10);
    app.Deck = app.Deck(11:end);

    app.OpponentHand = cellfun(@(x) {x}, app.OpponentHand);

    % Update ItemsData and Items based on the length of PlayerHand
    itemsCellArray = cell(1, length(app.PlayerHand));
    for i = 1:length(app.PlayerHand)
        itemsCellArray{i} = char(app.PlayerHand{i});
    end

    app.CartiListBox.ItemsData = 1:length(app.PlayerHand);
    app.CartiListBox.Items = itemsCellArray;

    % Check if the current Value is valid and adjust if necessary
    if isempty(app.CartiListBox.Value) || app.CartiListBox.Value > length(app.PlayerHand)
        app.CartiListBox.Value = min(1, length(app.PlayerHand));
    end

    app.showOpponentCardBacks();
    app.showDeckCardBacks();

    app.checkGameOver();
end

        function showTableCard(app)
            app.TableCard = app.Deck{1};
            app.Deck = app.Deck(2:end);
            app.showCardImage(app.TableCard, app.TableCardAxes);
        end

        function showOpponentCardBacks(app)
            for i = 1:length(app.OpponentHand)
                app.showCardImage(app.BackCardImage, app.OpponentCardAxes);
            end
        end

        function showDeckCardBacks(app)
    for i = 1:length(app.Deck)
        app.showCardImage(app.BackCardImage, app.DeckCardAxes);
    end
end

        function showCardImage(app, card, axes)
            imagePath = fullfile(app.CardsFolderPath, [card, '.png']);
            if exist(imagePath, 'file')
                cardImage = imread(imagePath);
            else
                cardImage = imread(fullfile(app.CardsFolderPath, app.BackCardImage));
            end
            image(cardImage, 'Parent', axes);
            axis(axes, 'off');
        end

        function updatePlayerCardImages(app)
            for i = 1:length(app.PlayerHand)
                app.showCardImage(app.PlayerHand{i}, app.PlayerCardAxes);
            end
        end

        function updateDeckCardImage(app)
            if ~isempty(app.Deck)
                app.showCardImage(app.Deck{1}, app.DeckCardAxes);
            else
                cla(app.DeckCardAxes, 'reset');
            end
        end

        function checkGameOver(app)
            if isempty(app.PlayerHand) || isempty(app.OpponentHand) || isempty(app.Deck)
                app.GameOver = true;
                uialert(app.UIFigure, 'Joc terminat!', 'Sfârșit de joc', 'Icon', 'info');

                if app.GameOver
                    app.LasaCarteButton.Enable = 'off';
                    app.TrageCarteButton.Enable = 'off';
                    app.ImparteCartiButton.Enable = 'off';
                end
            end
        end

        function isValid = isValidMove(app, card)
            isValid = strcmp(card(1:end-5), app.TableCard(1:end-5)) || strcmp(card(end-4:end), app.TableCard(end-4:end));
        end

        function opponentDrawCard(app)
            if ~isempty(app.Deck)
                drawnCard = app.Deck{1};
                app.OpponentHand = [app.OpponentHand, drawnCard];
                app.Deck = app.Deck(2:end);

                app.showOpponentCardBacks();
            else
                uialert(app.UIFigure, 'Pachetul de cărți este gol!', 'Eroare', 'Icon', 'error');
            end
        end

        function moves = getValidMoves(app)
            moves = app.OpponentHand(arrayfun(@(i) app.isValidMove(app.OpponentHand{i}), 1:length(app.OpponentHand)));
        end

        function updateSelectedCardImage(app, ~)
         selectedCardIndex = app.CartiListBox.Value;

    if ~isempty(selectedCardIndex) && ismember(selectedCardIndex, 1:length(app.PlayerHand))
        selectedCard = app.PlayerHand{selectedCardIndex};
        app.showCardImage(selectedCard, app.PlayerCardAxes);
    else
        % If no card is selected or an invalid selection is made, show the back card image
        app.showCardImage(app.BackCardImage, app.PlayerCardAxes);
    end
end

        function valid = canPlaceCard(app, selectedCard)
            valid = false;

            if ischar(selectedCard) && isrow(selectedCard)
                if ~isempty(app.TableCard)
                    [rank1, suit1] = app.parseCard(app.TableCard);
                    [rank2, suit2] = app.parseCard(selectedCard);

                    valid = ~isempty(app.TableCard) && (strcmp(rank1, rank2) || strcmp(suit1, suit2) || strcmp(rank2, 'as') || strcmp(rank1, 'as'));
                else
                    valid = true;
                end
            end
        end

        function [rank, suit] = parseCard(~, card)
            parts = strsplit(card, '_');
            rank = parts{1};
            suit = parts{2};
        end

        function value = getRankValue(~, rank)
            switch rank
                case 'as'
                    value = 1;
                case {'j', 'q', 'k'}
                    value = 10;
                otherwise
                    value = str2double(rank);
            end
        end

        function opponentTurn(app)
            if ~app.GameOver
                possibleMoves = app.getValidMoves();
                if ~isempty(possibleMoves)
                    selectedCard = possibleMoves{randi(length(possibleMoves))};
                    app.TableCard = selectedCard;
                    app.OpponentHand = setdiff(app.OpponentHand, selectedCard);

                    app.updateTableCardImage();
                    app.showOpponentCardBacks();

                    app.checkGameOver();
                else
                    app.opponentDrawCard();
                end
            end
        end

        function updateTableCardImage(app)
            app.showCardImage(app.TableCard, app.TableCardAxes);
        end

        function valid = isValidDraw(app, drawnCard)
            valid = ~strcmp(drawnCard, 'spate') && ~strcmp(drawnCard, app.BackCardImage);
        end

        function setCartiListBoxCallback(app)
            app.CartiListBox.ValueChangedFcn = createCallbackFcn(app, @updateSelectedCardImage, true);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LasaCarteButton
        function LasaCarteButtonPushed(app, event)
            if ~isempty(app.PlayerHand) && ~isempty(app.CartiListBox.Items)
        selectedCardIndex = app.CartiListBox.Value;

        if all(selectedCardIndex <= length(app.PlayerHand))
            selectedCard = app.PlayerHand{selectedCardIndex};
            validMove = app.canPlaceCard(selectedCard);

            if validMove
                app.TableCard = selectedCard;
                app.PlayerHand(selectedCardIndex) = [];

                app.updatePlayerCardImages();
                app.showCardImage(app.TableCard, app.TableCardAxes);
                app.showOpponentCardBacks();

                app.checkGameOver();
                app.opponentTurn();

                % Update the ListBox after "Lasa carte"
                app.CartiListBox.ItemsData = 1:length(app.PlayerHand);
                app.CartiListBox.Items = cellfun(@char, app.PlayerHand, 'UniformOutput', false);
                app.CartiListBox.Value = min(selectedCardIndex, length(app.PlayerHand));
            else
                uialert(app.UIFigure, 'Nu puteți lăsa jos această carte!', 'Eroare', 'Icon', 'error');
            end
        else
            uialert(app.UIFigure, 'Vă rugăm să selectați o carte validă!', 'Eroare', 'Icon', 'error');
        end
           end
        end

        % Button pushed function: TrageCarteButton
        function TrageCarteButtonPushed(app, event)
     if ~isempty(app.Deck) && length(app.Deck) >= 1
        drawnCard = app.Deck{1};
        app.PlayerHand = [app.PlayerHand, drawnCard];
        app.Deck = app.Deck(2:end);

        % Update ItemsData and Items based on the length of PlayerHand
        itemsCellArray = cell(1, length(app.PlayerHand));
        for i = 1:length(app.PlayerHand)
            itemsCellArray{i} = char(app.PlayerHand{i});
        end

        app.CartiListBox.ItemsData = 1:length(app.PlayerHand);
        app.CartiListBox.Items = itemsCellArray;

        app.updateDeckCardImage();
        app.showCardImage(drawnCard, app.PlayerCardAxes);

        % Show all deck cards facing downwards
        app.showDeckCardBacks();

        app.opponentTurn();

        % Check if the deck is empty, and if so, shut down the application
        if isempty(app.Deck)
            uialert(app.UIFigure, 'Pachetul de cărți este gol! Aplicația se va închide.', 'Sfârșit de joc', 'Icon', 'info');
            pause(2);  % Optional: Give users time to see the message
            delete(app.UIFigure);  % Close the application
        end
    else
        uialert(app.UIFigure, 'Pachetul de cărți este gol!', 'Eroare', 'Icon', 'error');
    end
        end

        % Button pushed function: ImparteCartiButton
        function ImparteCartiButtonPushed(app, event)
            app.createDeck();
            app.shuffleDeck();
            app.dealCards();

    if ~isempty(app.PlayerHand) && ~isempty(app.OpponentHand)
        app.showTableCard();
        app.showDeckCardBacks();

        app.updatePlayerCardImages();
        app.showOpponentCardBacks();

        % Set the ValueChangedFcn for CartiListBox
        app.CartiListBox.ValueChangedFcn = createCallbackFcn(app, @updateSelectedCardImage, true);
    else
        uialert(app.UIFigure, 'Jocul nu poate începe deoarece o mână nu a primit cărți!', 'Eroare', 'Icon', 'error');
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlayerCardAxes = uiaxes(app.UIFigure);
            app.PlayerCardAxes.XTick = [];
            app.PlayerCardAxes.YTick = [];
            app.TableCardAxes = uiaxes(app.UIFigure);
            app.TableCardAxes.XTick = [];
            app.TableCardAxes.YTick = [];
            app.OpponentCardAxes = uiaxes(app.UIFigure);
            app.OpponentCardAxes.XTick = [];
            app.OpponentCardAxes.XTickLabel = '';
            app.OpponentCardAxes.YTick = [];
            app.DeckCardAxes = uiaxes(app.UIFigure);
            app.DeckCardAxes.XTick = [];
            app.DeckCardAxes.YTick = [];
            app.CartiListBoxLabel = uilabel(app.UIFigure);
            app.CartiListBoxLabel.Text = 'Carti';
            app.CartiListBox = uilistbox(app.UIFigure);
            app.CartiListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5'};
            app.CartiListBox.Value = {};
            app.LasaCarteButton = uibutton(app.UIFigure, 'push');
            app.LasaCarteButton.ButtonPushedFcn = createCallbackFcn(app, @LasaCarteButtonPushed, true);
            app.LasaCarteButton.Text = 'Lasa Carte';
            app.TrageCarteButton = uibutton(app.UIFigure, 'push');
            app.TrageCarteButton.ButtonPushedFcn = createCallbackFcn(app, @TrageCarteButtonPushed, true);
            app.TrageCarteButton.Text = 'Trage Carte';
            app.ImparteCartiButton = uibutton(app.UIFigure, 'push');
            app.ImparteCartiButton.ButtonPushedFcn = createCallbackFcn(app, @ImparteCartiButtonPushed, true);
            app.ImparteCartiButton.Text = 'Imparte Carti';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = macao

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end